classdef LinExpPlots < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ClearPlotsButton    matlab.ui.control.Button
        btnPlotExponential  matlab.ui.control.Button
        btnPlotLinear       matlab.ui.control.Button
        pltExponential      matlab.ui.control.UIAxes
        pltLinear           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnPlotLinear
        function btnPlotLinearButtonPushed(app, event)
            app.pltLinear.Title.String = sprintf('Linear Plot');
            x=linspace(0,10);
            y=x;
            plot(app.pltLinear,x,y);
            app.pltLinear.YLabel.String = 'Y data';
            app.pltLinear.XLabel.String = 'X data';
            app.pltLinear.YTick = [0,2,4,6,8,10];
            app.pltLinear.XTick = [0,2,4,6,8,10];

        end

        % Button pushed function: btnPlotExponential
        function btnPlotExponentialButtonPushed(app, event)
            app.pltExponential.Title.String = 'Exponential Plot';
            x=linspace(0,10);
            y=x.^2;
            plot(app.pltExponential,x,y);
            app.pltExponential.YLabel.String = 'Y data';
            app.pltExponential.XLabel.String = 'X data';
            app.pltExponential.YTick = [0,20,40,60,80,100];
            app.pltExponential.XTick = [0,2,4,6,8,10];

        end

        % Button pushed function: ClearPlotsButton
        function ClearPlotsButtonPushed(app, event)
            plots = [app.pltLinear app.pltExponential];
            for n = 1:length(plots)
                cla(plots(n))
                title(plots(n), '');
                xlabel(plots(n), '');
                ylabel(plots(n), '');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.pltLinear = uiaxes(app.UIFigure);
            zlabel(app.pltLinear, 'Z')
            app.pltExponential = uiaxes(app.UIFigure);
            zlabel(app.pltExponential, 'Z')
            app.pltExponential.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.pltExponential.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.btnPlotLinear = uibutton(app.UIFigure, 'push');
            app.btnPlotLinear.ButtonPushedFcn = createCallbackFcn(app, @btnPlotLinearButtonPushed, true);
            app.btnPlotLinear.Text = 'Plot Linear';
            app.btnPlotExponential = uibutton(app.UIFigure, 'push');
            app.btnPlotExponential.ButtonPushedFcn = createCallbackFcn(app, @btnPlotExponentialButtonPushed, true);
            app.btnPlotExponential.Text = 'Plot Exponential';
            app.ClearPlotsButton = uibutton(app.UIFigure, 'push');
            app.ClearPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotsButtonPushed, true);
            app.ClearPlotsButton.Text = 'Clear Plots';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LinExpPlots

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end